classdef xjm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        clear                           matlab.ui.control.Button
        F1_8                            matlab.ui.control.Slider
        Frequency800020000hzSliderLabel  matlab.ui.control.Label
        F1_7                            matlab.ui.control.Slider
        Frequency40008000hzSliderLabel  matlab.ui.control.Label
        F1_5                            matlab.ui.control.Slider
        Frequency10002000hzSlider_2Label  matlab.ui.control.Label
        F1_6                            matlab.ui.control.Slider
        Frequency20004000hzSliderLabel  matlab.ui.control.Label
        F1_4                            matlab.ui.control.Slider
        Frequency15001000hzSliderLabel  matlab.ui.control.Label
        F1_2                            matlab.ui.control.Slider
        Frequency100200hzSliderLabel    matlab.ui.control.Label
        F1_1                            matlab.ui.control.Slider
        Frequency11100hzSliderLabel     matlab.ui.control.Label
        F1_3                            matlab.ui.control.Slider
        Frequency200500hzSliderLabel    matlab.ui.control.Label
        filteroff                       matlab.ui.control.Button
        filteron                        matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        Label_8                         matlab.ui.control.Label
        RMS                             matlab.ui.control.EditField
        Label_7                         matlab.ui.control.Label
        Average                         matlab.ui.control.EditField
        Label_6                         matlab.ui.control.Label
        VPP                             matlab.ui.control.EditField
        Label_5                         matlab.ui.control.Label
        Peak                            matlab.ui.control.EditField
        Label_4                         matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        Label_2                         matlab.ui.control.Label
        STOPSOUND                       matlab.ui.control.Button
        audioplay                       matlab.ui.control.Button
        CHOOSEPATH                      matlab.ui.control.Button
        FILEPATH                        matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        FANALYSIS                       matlab.ui.control.UIAxes
        ONETHIRDANALYSIS                matlab.ui.control.UIAxes
        f_t_A                           matlab.ui.control.UIAxes
        TIMECH                          matlab.ui.control.UIAxes
    end


    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FILEPATH
        function FILEPATHValueChanged(app, event)
            value = app.FILEPATH.Value;
            
        end

        % Button pushed function: STOPSOUND
        function STOPSOUNDButtonPushed(app, event)
            %停止播放按钮
            clear sound;
            global newtimer;  
            stop(newtimer);
            set(app.Lamp,'color',[0,0,0]);
        end

        % Button pushed function: audioplay
        function audioplayButtonPushed(app, event)
            %播放音乐
            set(app.Lamp,'color',[0,1,0]);%设置图形属性 颜色：纯绿色
            cla(app.ONETHIRDANALYSIS)%清除坐标区
            cla(app.f_t_A)
            
           
        end

        % Button pushed function: CHOOSEPATH
        function CHOOSEPATHButtonPushed(app, event)
            [app.filename,app.filepath]=uigetfile('*.mp3','请选择文件');
            
            app.FILEPATH.Value=[app.filepath,app.filename];
        end

        % Button down function: TIMECH
        function TIMECHButtonDown(app, event)

            
        end

        % Button down function: ONETHIRDANALYSIS
        function ONETHIRDANALYSISButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TIMECH = uiaxes(app.UIFigure);
            title(app.TIMECH, '时域信号')
            xlabel(app.TIMECH, 't')
            ylabel(app.TIMECH, 'Y')
            zlabel(app.TIMECH, 'Z')
            app.TIMECH.ButtonDownFcn = createCallbackFcn(app, @TIMECHButtonDown, true);
            app.f_t_A = uiaxes(app.UIFigure);
            title(app.f_t_A, '谱阵分析')
            xlabel(app.f_t_A, 'f')
            ylabel(app.f_t_A, 't')
            app.f_t_A.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.f_t_A.ZTickLabel = {'0'; '0.5'; '1'};
            app.ONETHIRDANALYSIS = uiaxes(app.UIFigure);
            title(app.ONETHIRDANALYSIS, '1/3倍频程分析')
            xlabel(app.ONETHIRDANALYSIS, 'f')
            ylabel(app.ONETHIRDANALYSIS, 'Y')
            zlabel(app.ONETHIRDANALYSIS, 'Z')
            app.ONETHIRDANALYSIS.ButtonDownFcn = createCallbackFcn(app, @ONETHIRDANALYSISButtonDown, true);
            app.FANALYSIS = uiaxes(app.UIFigure);
            title(app.FANALYSIS, '频域分析')
            xlabel(app.FANALYSIS, 'log10(f)')
            ylabel(app.FANALYSIS, 'Y')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '文件路径';
            app.FILEPATH = uieditfield(app.UIFigure, 'text');
            app.FILEPATH.ValueChangedFcn = createCallbackFcn(app, @FILEPATHValueChanged, true);
            app.CHOOSEPATH = uibutton(app.UIFigure, 'push');
            app.CHOOSEPATH.ButtonPushedFcn = createCallbackFcn(app, @CHOOSEPATHButtonPushed, true);
            app.CHOOSEPATH.Text = '选择音频文件';
            app.audioplay = uibutton(app.UIFigure, 'push');
            app.audioplay.ButtonPushedFcn = createCallbackFcn(app, @audioplayButtonPushed, true);
            app.audioplay.Text = '播放音频';
            app.STOPSOUND = uibutton(app.UIFigure, 'push');
            app.STOPSOUND.ButtonPushedFcn = createCallbackFcn(app, @STOPSOUNDButtonPushed, true);
            app.STOPSOUND.Text = '停止播放';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '运行状态';
            app.Lamp = uilamp(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '峰值';
            app.Peak = uieditfield(app.UIFigure, 'text');
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '峰峰值';
            app.VPP = uieditfield(app.UIFigure, 'text');
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '平均值';
            app.Average = uieditfield(app.UIFigure, 'text');
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '有效值';
            app.RMS = uieditfield(app.UIFigure, 'text');
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '频谱分析';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'功率谱', '幅频', '相频', '实频', '虚频'};
            app.DropDown.Value = '功率谱';
            app.filteron = uibutton(app.UIFigure, 'push');
            app.filteron.Text = '开启均衡器';
            app.filteroff = uibutton(app.UIFigure, 'push');
            app.filteroff.Text = '关闭均衡器';
            app.Frequency200500hzSliderLabel = uilabel(app.UIFigure);
            app.Frequency200500hzSliderLabel.Text = {'Frequency'; '200-500hz'; ''};
            app.F1_3 = uislider(app.UIFigure);
            app.F1_3.Limits = [0 1];
            app.Frequency11100hzSliderLabel = uilabel(app.UIFigure);
            app.Frequency11100hzSliderLabel.Text = {'Frequency1'; '1-100hz'; ''};
            app.F1_1 = uislider(app.UIFigure);
            app.F1_1.Limits = [0 1];
            app.F1_1.Value = 1;
            app.Frequency100200hzSliderLabel = uilabel(app.UIFigure);
            app.Frequency100200hzSliderLabel.Text = {'Frequency'; '100-200hz'};
            app.F1_2 = uislider(app.UIFigure);
            app.F1_2.Limits = [0 1];
            app.Frequency15001000hzSliderLabel = uilabel(app.UIFigure);
            app.Frequency15001000hzSliderLabel.Text = {'Frequency1'; '500-1000hz'; ''};
            app.F1_4 = uislider(app.UIFigure);
            app.F1_4.Limits = [0 1];
            app.F1_4.Value = 1;
            app.Frequency20004000hzSliderLabel = uilabel(app.UIFigure);
            app.Frequency20004000hzSliderLabel.Text = {'Frequency'; '2000-4000hz'};
            app.F1_6 = uislider(app.UIFigure);
            app.F1_6.Limits = [0 1];
            app.Frequency10002000hzSlider_2Label = uilabel(app.UIFigure);
            app.Frequency10002000hzSlider_2Label.Text = {'Frequency'; '1000-2000hz'};
            app.F1_5 = uislider(app.UIFigure);
            app.F1_5.Limits = [0 1];
            app.Frequency40008000hzSliderLabel = uilabel(app.UIFigure);
            app.Frequency40008000hzSliderLabel.Text = {'Frequency'; '4000-8000hz'};
            app.F1_7 = uislider(app.UIFigure);
            app.F1_7.Limits = [0 1];
            app.Frequency800020000hzSliderLabel = uilabel(app.UIFigure);
            app.Frequency800020000hzSliderLabel.Text = {'Frequency'; '8000-20000hz'};
            app.F1_8 = uislider(app.UIFigure);
            app.F1_8.Limits = [0 1];
            app.clear = uibutton(app.UIFigure, 'push');
            app.clear.Text = '清除图像';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = xjm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end