classdef xjm2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Label_8           matlab.ui.control.Label
        RMS               matlab.ui.control.NumericEditField
        Label_7           matlab.ui.control.Label
        Average           matlab.ui.control.NumericEditField
        Label_6           matlab.ui.control.Label
        VPP               matlab.ui.control.NumericEditField
        Label_5           matlab.ui.control.Label
        Peak              matlab.ui.control.NumericEditField
        Label_4           matlab.ui.control.Label
        DropDown          matlab.ui.control.DropDown
        Label_3           matlab.ui.control.Label
        Lamp_2            matlab.ui.control.Lamp
        Lamp_2Label       matlab.ui.control.Label
        save_data         matlab.ui.control.Button
        Lamp              matlab.ui.control.Lamp
        Label_2           matlab.ui.control.Label
        begin             matlab.ui.control.Button
        CHOOSEPATH        matlab.ui.control.Button
        FILEPATH          matlab.ui.control.EditField
        Label             matlab.ui.control.Label
        Ashengji          matlab.ui.control.UIAxes
        ONETHIRD_fc       matlab.ui.control.UIAxes
        ONETHIRD_1_2_3_4  matlab.ui.control.UIAxes
        TIMECH            matlab.ui.control.UIAxes
        FANALYSIS         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filepath
        filename
        fs
        state
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.filepath='E:\matlab2021aa\sum_proj\audio process\two\'; %初始的文件地址
            app.filename='36波形.xlsx';
            app.FILEPATH.Value= [app.filepath,app.filename];
                        %音频文件播放及1/3倍频程分析。
            global fc
            global value
            global oc6
            global nc  
            global n
            global x
            global a

%             global fs
            fc= [1 1.25 1.6 2 2.5 3.15 4 5 6.3 8 10 12.5 16 20 25 31.5 40 50 63 80 100 125 160 200 250 315 400 500 630 800 1000 1250 1600 2000 2500 3150 4000 5000 6300 8000 10000 12500 16000 20000 25000 31500 40000 50000 63000 80000 100000 125000 160000 200000];
            oc6=2^(1/6);%中心频率与下限频率的比值 %oc6=1.1225

            n=length(x);%输入数据的长度 %n=2048
            nc=length(fc);%取中心频率总的长度  %nc=54
            set(app.Lamp,'color',[0,0,0]);
            set(app.Lamp_2,'color',[0,0,0]);


            x=[];       %时域信号
            a=[];       %fft之后的原始信号
            app.state=0;
          
        end

        % Button pushed function: CHOOSEPATH
        function CHOOSEPATHButtonPushed(app, event)
            [app.filename, app.filepath]=uigetfile('*.xlsx','请选择文件');
            app.FILEPATH.Value=[app.filepath,app.filename]; 
        end

        % Button pushed function: begin
        function beginButtonPushed(app, event)
            %对应播放音频
            set(app.Lamp,'color',[0,1,0]);
            cla(app.Ashengji)
            cla(app.ONETHIRD_fc)
            cla(app.ONETHIRD_1_2_3_4)
            cla(app.FANALYSIS)
            cla(app.TIMECH)
            
            global fs
            global x
            global t
            
            %开始读取数据
            Signal = xlsread(app.FILEPATH.Value);   % 读Excel文件
            t = Signal(:,1)-1;            % 取第一列时间序列，减1处理
            dt = t(2)-t(1);               % 时间间隔（假设是均匀采样）这段代码就是针对等时间间隔的采样。
            fs = 1/dt;  %fs=8192 奈奎斯特采样频率
            x=Signal(:,2);%速度
            
        end

        % Button pushed function: save_data
        function save_dataButtonPushed(app, event)
           set(app.Lamp_2,'color',[0,1,0]);%绿色
 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Callback function
        function DropDownOpening(app, event)
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
        global value;    
        value = app.DropDown.Value;
        
        end

        % Drop down opening function: DropDown
        function DropDownOpening2(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FANALYSIS = uiaxes(app.UIFigure);
            title(app.FANALYSIS, '频域特征')
            xlabel(app.FANALYSIS, 'log10(f)')
            ylabel(app.FANALYSIS, 'Y')
            zlabel(app.FANALYSIS, 'Z')
            app.FANALYSIS.XTick = [1 1.5 2 2.5 3 3.5 4 4.5];
            app.FANALYSIS.XScale = 'log';
            app.FANALYSIS.XTickLabel = {'log10(f)'};
            app.TIMECH = uiaxes(app.UIFigure);
            title(app.TIMECH, '时域波形')
            xlabel(app.TIMECH, 't(s)')
            ylabel(app.TIMECH, 'Y')
            zlabel(app.TIMECH, 'Z')
            app.ONETHIRD_1_2_3_4 = uiaxes(app.UIFigure);
            title(app.ONETHIRD_1_2_3_4, '三分之一倍频，x对应阿拉伯')
            xlabel(app.ONETHIRD_1_2_3_4, 't(s)')
            ylabel(app.ONETHIRD_1_2_3_4, 'Y')
            zlabel(app.ONETHIRD_1_2_3_4, 'Z')
            app.ONETHIRD_fc = uiaxes(app.UIFigure);
            title(app.ONETHIRD_fc, '三分之一倍频，x对应fc')
            xlabel(app.ONETHIRD_fc, 'X')
            ylabel(app.ONETHIRD_fc, 'Y')
            zlabel(app.ONETHIRD_fc, 'Z')
            app.Ashengji = uiaxes(app.UIFigure);
            title(app.Ashengji, 'A声级')
            xlabel(app.Ashengji, 'X')
            ylabel(app.Ashengji, 'Y')
            zlabel(app.Ashengji, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '文件路径';
            app.FILEPATH = uieditfield(app.UIFigure, 'text');
            app.CHOOSEPATH = uibutton(app.UIFigure, 'push');
            app.CHOOSEPATH.ButtonPushedFcn = createCallbackFcn(app, @CHOOSEPATHButtonPushed, true);
            app.CHOOSEPATH.Text = '选择文件';
            app.begin = uibutton(app.UIFigure, 'push');
            app.begin.ButtonPushedFcn = createCallbackFcn(app, @beginButtonPushed, true);
            app.begin.Text = '开始处理';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '状态';
            app.Lamp = uilamp(app.UIFigure);
            app.save_data = uibutton(app.UIFigure, 'push');
            app.save_data.ButtonPushedFcn = createCallbackFcn(app, @save_dataButtonPushed, true);
            app.save_data.Text = '保存数据';
            app.Lamp_2Label = uilabel(app.UIFigure);
            app.Lamp_2Label.Text = '状态';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Tooltip = {'单位介绍：'; '幅频(x轴HZ)'; '相频(x轴HZ,y轴弧度)'; '实频(x轴HZ)'; '虚频(x轴HZ)'; '功率谱(x轴hz y轴db)'; ''; ''};
            app.Label_3.Text = '频域特征';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'幅频', '相频', '实频', '虚频', '功率谱'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening2, true);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Tooltip = {'幅频(x轴hz)'; '相频(x轴hz,y轴弧度)'; '实频(x轴hz)'; '虚频(x轴hz)'; '功率谱(x轴hz,y轴分贝)'};
            app.DropDown.Value = '幅频';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '峰值';
            app.Peak = uieditfield(app.UIFigure, 'numeric');
            app.Peak.Editable = 'off';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '峰峰值';
            app.VPP = uieditfield(app.UIFigure, 'numeric');
            app.VPP.Editable = 'off';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '平均值';
            app.Average = uieditfield(app.UIFigure, 'numeric');
            app.Average.Editable = 'off';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '有效值';
            app.RMS = uieditfield(app.UIFigure, 'numeric');
            app.RMS.Editable = 'off';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '(保存的是三分之一倍频处理数据)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = xjm2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end