classdef xjm2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Lamp_3            matlab.ui.control.Lamp
        Lamp_3Label       matlab.ui.control.Label
        clear_process     matlab.ui.control.Button
        ListBox           matlab.ui.control.ListBox
        Label_9           matlab.ui.control.Label
        Label_8           matlab.ui.control.Label
        RMS               matlab.ui.control.NumericEditField
        Label_7           matlab.ui.control.Label
        Average           matlab.ui.control.NumericEditField
        Label_6           matlab.ui.control.Label
        VPP               matlab.ui.control.NumericEditField
        Label_5           matlab.ui.control.Label
        Peak              matlab.ui.control.NumericEditField
        Label_4           matlab.ui.control.Label
        Lamp_2            matlab.ui.control.Lamp
        Lamp_2Label       matlab.ui.control.Label
        save_data         matlab.ui.control.Button
        Lamp              matlab.ui.control.Lamp
        Label_2           matlab.ui.control.Label
        begin             matlab.ui.control.Button
        CHOOSEPATH        matlab.ui.control.Button
        FILEPATH          matlab.ui.control.EditField
        Label             matlab.ui.control.Label
        Ashengji          matlab.ui.control.UIAxes
        ONETHIRD_fc       matlab.ui.control.UIAxes
        ONETHIRD_1_2_3_4  matlab.ui.control.UIAxes
        TIMECH            matlab.ui.control.UIAxes
        FANALYSIS         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filepath
        filename
        state
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.filepath='E:\matlab2021aa\sum_proj\audio process\two\'; %初始的文件地址
            app.filename='36波形.xlsx';
            app.FILEPATH.Value= [app.filepath,app.filename];
                        %excel文件播放及1/3倍频程分析。
            global fc
            global oc6
            global nc  
            global n
            global x
            global a

%             global fs
            fc= [1 1.25 1.6 2 2.5 3.15 4 5 6.3 8 10 12.5 16 20 25 31.5 40 50 63 80 100 125 160 200 250 315 400 500 630 800 1000 1250 1600 2000 2500 3150 4000 5000 6300 8000 10000 12500 16000 20000 25000 31500 40000 50000 63000 80000 100000 125000 160000 200000];
            oc6=2^(1/6);%中心频率与下限频率的比值 %oc6=1.1225

            n=length(x);%输入数据的长度 %n=2048
            nc=length(fc);%取中心频率总的长度  %nc=54
            set(app.Lamp,'color',[0,0,0]);
            set(app.Lamp_2,'color',[0,0,0]);
            
            x=[];       %时域信号
            a=[];       %fft之后的原始信号
            app.state=0;
          
        end

        % Button pushed function: CHOOSEPATH
        function CHOOSEPATHButtonPushed(app, event)
            [app.filename, app.filepath]=uigetfile('*.xlsx','请选择文件');
            app.FILEPATH.Value=[app.filepath,app.filename]; 
        end

        % Button pushed function: begin
        function beginButtonPushed(app, event)
            set(app.Lamp,'color',[0,1,0]);%设置信号灯
            set(app.Lamp_3,'color',[0.65,0.65,0.65]);%设置灰色
            cla(app.Ashengji) %清除其中的值
            cla(app.ONETHIRD_fc)
            cla(app.ONETHIRD_1_2_3_4)
            cla(app.FANALYSIS)
            cla(app.TIMECH)
            global a
            global fs
            global x
            global t
           
            Signal = xlsread(app.FILEPATH.Value);   % 读Excel文件
            t = Signal(:,1)-1;            % di一列时间序列，减1处理
            dt = t(2)-t(1);               % 时间间隔（假设是均匀采样）这段代码就是针对等时间间隔的采样。
            fs = 1/dt;  %fs=8192 奈奎斯特采样频率
            x=Signal(:,2);%速度
            %%
            %---------------------------------------------------------
            fc= [1 1.25 1.6 2 2.5 3.15 4 5 6.3 8 10 12.5 16 20 25 31.5 40 50 63 80 100 125 160 200 250 315 400 500 630 800 1000 1250 1600 2000 2500 3150 4000 5000 6300 8000 10000 12500 16000 20000 25000 31500 40000 50000 63000 80000 100000 125000 160000 200000];
            oc6=2^(1/6);%中心频率与下限频率的比值 %oc6=1.1225
            nc=length(fc);   %nc=54
            n=length(x);%输入数据的长度 %n=2048
            nfft=2^nextpow2(n);%大于并接近n的2的幂次方长度  %2048
            a=fft(x,nfft);%FFT变换  a=(2048x1) % a复数
            yc = zeros(1,nc);
            for j=1:nc %nc=44
                fl=fc(j)/oc6;
                fu=fc(j)*oc6;  %fc=20khz对应fl=224480hz
                nl=round(fl*nfft/fs+1); %下限频率对应的序号
                nu=round(fu*nfft/fs+1);%上限频率对应的序号
                if fu>fs/2 %如果上限频率大于半谱频率则循环中断
                    m=j-1; disp(m);
                    break
                end
                disp(j);
                b=zeros(1,nfft);
                b(nl:nu)=a(nl:nu);
                b(nfft-nu+1:nfft-nl+1)=a(nfft-nu+1:nfft-nl+1);%以每个中心频率段为通带进行带通频域滤波
                c=ifft(b,nfft);%c是复数
                yc(j)=sqrt(var(real(c(1:n)))); %信号c零均值的  %yc=RMS %每个中心频率段的RMS 	%计算对应每个中心频率段的有效值%注意，matlab函数var和std认为操作数是度量总体的一个样本，所以使用的公式是实际值与期望值之差的平方和再除以（N-1）
            end


            %----------------------时域波形---------------------------------------------
            t=0:1/fs:(n-1)/fs;
            plot(app.TIMECH,t,x);
            %------------三分之倍频程谱，x对应fc的数目------------------------------

            bar(app.ONETHIRD_1_2_3_4,yc(1:m));

            %--------------三分之一倍频每一中心频率的RMS值------------------------
            bar(app.ONETHIRD_fc,fc(1:m),yc(1:m));

            %------------------------绘制A声级-------------------------------------
            yc=10*log10(yc/1e-10); %yc=(1x60)
            % %yc = 20 * log10(yc / (20e-5)); %严格标准写法
            % %yc = 20 * log10(yc /1 * 10^(-6));  % 参考加速度（通常是1微米/秒??）
            %xjm
            cf1=zeros(1,m);%m=36 %A声级修正
            cf1(1,11:41)=[-70.4,-63.4,-56.7,-50.5,-44.7,-39.4,-34.6,-30.2,-26.2,-22.5,-19.1,-16.1,-13.4,-10.9,-8.6,...
                -6.6,-4.8,-3.2,-1.9,-0.8,0,0.6,1.0,1.2,1.3,1.2,1.0,0.5,-0.1,-1.1,-2.5];%20~20khz
            yc1=yc(1:m)+cf1(1:m);%10~20khz  %10零对应0~8hz
            plot(app.Ashengji,fc(14:m),yc1(14:m),'r-s','LineWidth',1.5);  %从20Hz开始画，因此取14。20hz是第十四个频率
            app.Ashengji.XLim=[20 20000];

            %---------------------添功能--------------------------------

            app.Average.Value=mean(x); %在文本框显示 %每一个时间段给一个值
            app.RMS.Value=rms(x);
            app.Peak.Value=max(x);
            app.VPP.Value=peak2peak(x);

            
        end

        % Button pushed function: save_data
        function save_dataButtonPushed(app, event)
            set(app.Lamp_2,'color',[0,1,0]);%绿色      
            set(app.Lamp,'color',[0.5 0.5 0.5]);%灰色 
            set(app.Lamp_3,'color',[0.5 0.5 0.5]);%灰色 
            folder = pwd; % 获取当前工作文件夹路径
            
            % 将所有 UIAxes 放入一个数组中，便于循环
            axesList = [app.TIMECH,app.FANALYSIS , app.ONETHIRD_1_2_3_4, app.ONETHIRD_fc, app.Ashengji];

            for i = 1:length(axesList)
                % 临时创建一个不可见图形窗口
                fig = figure('Visible', 'off');

                % 复制 App 中的 UIAxes 到新图形
                ax = copyobj(axesList(i), fig);
                set(ax, 'Units', 'Normalized', 'Position', [0.13 0.11 0.775 0.815]);  % 调整位置以适配图像

                % 设置保存路径和文件名
                app.filename = fullfile(folder, sprintf('waveform_Axes%d.png', i));

                % 保存为 PNG 图像（也可改为 .jpg、.eps 等）
                saveas(fig, app.filename);

                % 关闭图形窗口
                close(fig);
            end
          set(app.Lamp_2, 'Color', [0.5 0.5 0.5]);%灰色
 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Callback function
        function DropDownOpening(app, event)
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            Signal = xlsread(app.FILEPATH.Value); 
            t = Signal(:,1)-1;           
            dt = t(2)-t(1);              
            fs = 1/dt; 
            x=Signal(:,2);
           
            n=length(x);
            nfft=2^nextpow2(n);
            global a
            a=fft(x,nfft);
            ff=linspace(0,fs/2,ceil(n/2));
            Amplitude=2*abs(a)/nfft;
            Amplitude(1)=Amplitude(1)/n;
            Power=Amplitude.*Amplitude;
            Power=20*log10(Power);

             switch value %复选框
                case('功率谱')
                    plot(app.FANALYSIS,log10(ff),Power(1:ceil(n/2)));%db(功率谱)
                case('幅频')
                    plot(app.FANALYSIS,log10(ff),Amplitude(1:ceil(n/2)));%x轴hz  y轴Y
                case('相频')
                    ph = angle(a)*180/pi;
                    plot(app.FANALYSIS,log10(ff),ph(1:ceil(n/2)));%x轴hz  y轴Y
                case('实频')
                    r=real(a);
                    plot(app.FANALYSIS,log10(ff),r(1:ceil(n/2)));%x轴hz  y轴Y
                 case('虚 频')
                    ii=imag(a);
                    plot(app.FANALYSIS,log10(ff),ii(1:ceil(n/2)));drawnow; %x轴hz  y轴Y

            end
            drawnow; 
        end

        % Button pushed function: clear_process
        function clear_processButtonPushed(app, event)
            set(app.Lamp_3,'color',[1.00,0.00,0.00]);%红色
            set(app.Lamp,'color',[0.50,0.50,0.50]);%灰色
            cla(app.Ashengji) %清除其中的值
            cla(app.ONETHIRD_fc)
            cla(app.ONETHIRD_1_2_3_4)
            cla(app.FANALYSIS)
            cla(app.TIMECH)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FANALYSIS = uiaxes(app.UIFigure);
            title(app.FANALYSIS, '\color[rgb]{0,0,0}\fontname{Times New Roman,宋体}频域特征')
            xlabel(app.FANALYSIS, {'\fontname{Times New Roman,宋体}\color[rgb]{0,0,0}log(ff)'; ''})
            ylabel(app.FANALYSIS, 'Y')
            zlabel(app.FANALYSIS, 'Z')
            app.TIMECH = uiaxes(app.UIFigure);
            title(app.TIMECH, '\color[rgb]{0,0,0}\fontname{Times New Roman,宋体} 信号时域波形')
            xlabel(app.TIMECH, '\fontname{Times New Roman,宋体}\color[rgb]{0,0,0}时间(s)')
            ylabel(app.TIMECH, '\color[rgb]{0,0,0}\fontname{Times New Roman,宋体} 加速度 mm/s^2')
            zlabel(app.TIMECH, 'Z')
            app.ONETHIRD_1_2_3_4 = uiaxes(app.UIFigure);
            title(app.ONETHIRD_1_2_3_4, '\color[rgb]{0,0,0}\fontname{Times New Roman,宋体}三分之一倍频，x对应中心频率索引')
            xlabel(app.ONETHIRD_1_2_3_4, {'\fontname{Times New Roman,宋体}\color[rgb]{0,0,0}fc位置'; ''})
            ylabel(app.ONETHIRD_1_2_3_4, '\color[rgb]{0,0,0}\fontname{Times New Roman,宋体} 分贝(db)')
            zlabel(app.ONETHIRD_1_2_3_4, 'Z')
            app.ONETHIRD_fc = uiaxes(app.UIFigure);
            title(app.ONETHIRD_fc, '\color[rgb]{0,0,0}\fontname{Times New Roman,宋体}1/3倍频每一中心频率的RMS值')
            xlabel(app.ONETHIRD_fc, {'\fontname{Times New Roman,宋体}\color[rgb]{0,0,0}中心频率fc'; ''})
            ylabel(app.ONETHIRD_fc, '\color[rgb]{0,0,0}\fontname{Times New Roman,宋体} 分贝(db)')
            zlabel(app.ONETHIRD_fc, 'Z')
            app.Ashengji = uiaxes(app.UIFigure);
            title(app.Ashengji, '\color[rgb]{0,0,0}\fontname{Times New Roman,宋体}A声级')
            xlabel(app.Ashengji, '\fontname{Times New Roman,宋体}\color[rgb]{0,0,0}频率(Hz)')
            ylabel(app.Ashengji, '\color[rgb]{0,0,0}\fontname{Times New Roman,宋体} A声级(dB)')
            zlabel(app.Ashengji, 'Z')
            app.Ashengji.XScale = 'log';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '文件路径';
            app.FILEPATH = uieditfield(app.UIFigure, 'text');
            app.CHOOSEPATH = uibutton(app.UIFigure, 'push');
            app.CHOOSEPATH.ButtonPushedFcn = createCallbackFcn(app, @CHOOSEPATHButtonPushed, true);
            app.CHOOSEPATH.Text = '选择文件';
            app.begin = uibutton(app.UIFigure, 'push');
            app.begin.ButtonPushedFcn = createCallbackFcn(app, @beginButtonPushed, true);
            app.begin.Text = '开始处理';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '状态';
            app.Lamp = uilamp(app.UIFigure);
            app.save_data = uibutton(app.UIFigure, 'push');
            app.save_data.ButtonPushedFcn = createCallbackFcn(app, @save_dataButtonPushed, true);
            app.save_data.Text = '保存数据';
            app.Lamp_2Label = uilabel(app.UIFigure);
            app.Lamp_2Label.Text = '状态';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '峰值';
            app.Peak = uieditfield(app.UIFigure, 'numeric');
            app.Peak.Editable = 'off';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '峰峰值';
            app.VPP = uieditfield(app.UIFigure, 'numeric');
            app.VPP.Editable = 'off';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '平均值';
            app.Average = uieditfield(app.UIFigure, 'numeric');
            app.Average.Editable = 'off';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '有效值';
            app.RMS = uieditfield(app.UIFigure, 'numeric');
            app.RMS.Editable = 'off';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '(保存波形数据)';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '频域特征';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'幅频', '相频', '实频', '虚 频', '功率谱'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Tooltip = {'幅频(x轴hz)'; '相频(x轴hz,y轴弧度)'; '实频(x轴hz)'; '虚频(x轴hz)'; '功率谱(x轴hz,y轴分贝)'};
            app.ListBox.Value = '相频';
            app.clear_process = uibutton(app.UIFigure, 'push');
            app.clear_process.ButtonPushedFcn = createCallbackFcn(app, @clear_processButtonPushed, true);
            app.clear_process.Text = '清除图像';
            app.Lamp_3Label = uilabel(app.UIFigure);
            app.Lamp_3Label.Text = '状态';
            app.Lamp_3 = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = xjm2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end