classdef xjm3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Image             matlab.ui.control.Image
        Label_15          matlab.ui.control.Label
        Lamp_4            matlab.ui.control.Lamp
        begin_6           matlab.ui.control.Button
        monixinhao_shiyu  matlab.ui.control.Button
        phi_3             matlab.ui.control.NumericEditField
        P3Label           matlab.ui.control.Label
        phi_2             matlab.ui.control.NumericEditField
        Label_11          matlab.ui.control.Label
        P2Label           matlab.ui.control.Label
        phi_1             matlab.ui.control.NumericEditField
        P1Label           matlab.ui.control.Label
        DropDown_3        matlab.ui.control.DropDown
        Label_14          matlab.ui.control.Label
        DropDown_2        matlab.ui.control.DropDown
        Label_13          matlab.ui.control.Label
        DropDown          matlab.ui.control.DropDown
        Label_12          matlab.ui.control.Label
        addnosie          matlab.ui.control.Button
        pinlv_3           matlab.ui.control.NumericEditField
        F3Label           matlab.ui.control.Label
        pinlv_2           matlab.ui.control.NumericEditField
        F2Label           matlab.ui.control.Label
        pinlv_1           matlab.ui.control.NumericEditField
        F1Label           matlab.ui.control.Label
        fuzhi3            matlab.ui.control.NumericEditField
        A3Label           matlab.ui.control.Label
        fuzhi2            matlab.ui.control.NumericEditField
        A2Label           matlab.ui.control.Label
        fuzhi1            matlab.ui.control.NumericEditField
        A1Label           matlab.ui.control.Label
        zhiliu            matlab.ui.control.NumericEditField
        Lamp_3            matlab.ui.control.Lamp
        AdcLabel          matlab.ui.control.Label
        clear_process     matlab.ui.control.Button
        ListBox           matlab.ui.control.ListBox
        Label_9           matlab.ui.control.Label
        Label_8           matlab.ui.control.Label
        RMS               matlab.ui.control.NumericEditField
        Label_7           matlab.ui.control.Label
        Average           matlab.ui.control.NumericEditField
        Label_6           matlab.ui.control.Label
        VPP               matlab.ui.control.NumericEditField
        Label_5           matlab.ui.control.Label
        Peak              matlab.ui.control.NumericEditField
        Label_4           matlab.ui.control.Label
        Lamp_2            matlab.ui.control.Lamp
        save_data         matlab.ui.control.Button
        Lamp              matlab.ui.control.Lamp
        begin             matlab.ui.control.Button
        CHOOSEPATH        matlab.ui.control.Button
        FILEPATH          matlab.ui.control.EditField
        Label             matlab.ui.control.Label
        FANALYSIS_2       matlab.ui.control.UIAxes
        UIAxesFIR_5       matlab.ui.control.UIAxes
        pinghua           matlab.ui.control.UIAxes
        min_two_axes      matlab.ui.control.UIAxes
        one_third_lianxu  matlab.ui.control.UIAxes
        ONETHIRD_1_2_3_4  matlab.ui.control.UIAxes
        TIMECH            matlab.ui.control.UIAxes
        FANALYSIS         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filepath
        filename
        state
        y_total
        add_noise
        y_total_ac
        value_1
        event_1
%        isExcelDataLoaded = false ;   % 新增：Excel数据加载状态
%        isCustomSignalGenerated = false; % 新增：自定义信号生成状态
        Hd;              % FIR滤波器对象
        filteredSignal;  % 滤波后的信号
        Fs = 8192;       % 默认采样率（根据实际数据调整）
    end

    methods (Access = private)

        function y = waveform(app, type, f, t, phi)
            switch type
                case 'sin'
                    y = sin(2*pi*f*t + phi);
                case 'cos'
                    y = cos(2*pi*f*t + phi);
                otherwise
                    y = zeros(size(t));
            end
        end

%         function y = yuchuliways(app, way)%预处理的选择
%             switch way
%                 case '最小二乘法'%最小二乘法消除趋势项的前后对比图
%                     
%                     
%                     y = sin(2*pi*f*t + phi);
%                 case '平滑滤波'%smooth
%                     
%                     
%                     y = cos(2*pi*f*t + phi);
%                 case 'FIR滤波'%低通滤波
%                     
%                     
%                     y = cos(2*pi*f*t + phi);
%                     
%                 otherwise
%                     y = zeros(size(t));
%             end
%         end
%         function Hd = FIR_lowpass_returnFilter(app)
%             % 设计FIR滤波器
%             N     = 200;     % 阶数
%             Fpass = 200000;  % 通带频率
%             Fstop = 205000;  % 阻带频率
%             Apass = 0.1;     % 通带波纹(dB)
%             Fs    = app.Fs;  % 使用类属性中的采样率
% 
%             h = fdesign.lowpass('n,fp,fst,ap', N, Fpass, Fstop, Apass, app.Fs);
%             Hd = design(h, 'equiripple');
%         end

%         function results = filter_FIR(~,fs, wc1 ,wc2 , signal)
%             b = fir1(50, [wc1*2/fs wc2*2/fs]);
%             results = filtfilt(b,1,signal);
%         end

       function results = filter_FIR(~,fs, wc1 ,wc2 , signal)
            b = fir1(50, [wc1*2/fs wc2*2/fs]);
            results = filtfilt(b,1,signal);                        
       end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.filepath='E:\matlab2021aa\sum_proj\audio process\two\'; %初始的文件地址
            app.filename='36波形.xlsx';
            app.FILEPATH.Value= [app.filepath,app.filename];
                        %excel文件播放及1/3倍频程分析。
            global fc
            global oc6
            global nc  
            global n
            global x
            global a

%             global fs_up
            fc= [1 1.25 1.6 2 2.5 3.15 4 5 6.3 8 10 12.5 16 20 25 31.5 40 50 63 80 100 125 160 200 250 315 400 500 630 800 1000 1250 1600 2000 2500 3150 4000 5000 6300 8000 10000 12500 16000 20000 25000 31500 40000 50000 63000 80000 100000 125000 160000 200000];
            oc6=2^(1/6);%中心频率与下限频率的比值 %oc6=1.1225

            n=length(x);%输入数据的长度 %n=2048
            nc=length(fc);%取中心频率总的长度  %nc=54
            set(app.Lamp,'color',[0,0,0]);
            set(app.Lamp_2,'color',[0,0,0]);
            
            x=[];       %时域信号
            a=[];       %fft之后的原始信号
            app.state=0;


        end

        % Button pushed function: CHOOSEPATH
        function CHOOSEPATHButtonPushed(app, event)
            %             [app.filename, app.filepath]=uigetfile('*.xlsx','请选择文件');
            %              app.FILEPATH.Value=[app.filepath,app.filename];
            % 使用uigetfile获取文件信息
            [app.filename, app.filepath] = uigetfile('*.xlsx', '请选择文件');

            % 检查用户是否取消了选择
            if isequal(app.filename, 0) || isequal(app.filepath, 0)
                return; % 用户取消，不做任何操作
            end

            % 使用fullfile确保路径正确拼接
            full_path = fullfile(app.filepath, app.filename);

            % 更新EditField的值（确保为字符串）
            app.FILEPATH.Value = full_path;

            % 可选：更新类属性（如果需要）
            app.filename = app.filename;
            app.filepath = app.filepath;
        end

        % Button pushed function: begin
        function beginButtonPushed(app, event)
%             app.event_1=1;

%              t_1 = 0:0.001:1;           
            app.y_total_ac = 0;% 初始化
            

            set(app.Lamp,'color',[0,1,0]);%设置信号灯
            set(app.Lamp_3,'color',[0.65,0.65,0.65]);%设置灰色
            cla(app.one_third_lianxu) %清除其中的值

            cla(app.ONETHIRD_1_2_3_4)
            cla(app.FANALYSIS)
            cla(app.TIMECH)
            global a
            global fs_up
            global x
            global t
           
            Signal = xlsread(app.FILEPATH.Value);   % 读Excel文件
            t = Signal(:,1);            % di一列时间序列，减1处理
            dt = t(2)-t(1);               % 时间间隔（假设是均匀采样）这段代码就是针对等时间间隔的采样。
            fs_up = 1/dt;  %fs_up=8192 奈奎斯特采样频率
            x=Signal(:,2);%速度
%             whos x;
% 
% 
%             sig = double(x);           % 转为 double
%             sig = sig(:);              % 列向量
%             sig = sig(~isnan(sig) & ~isinf(sig));   % 去除 NaN/Inf
%             disp(class(sig));
%             disp(size(sig));
%             disp(any(isnan(sig)));
%             disp(any(isinf(sig)));
% 
%             disp('sig类型:'); disp(class(sig));    % 必须是 double
%             disp('sig尺寸:'); disp(size(sig));    % 必须是 [2048 1] 或 [1 2048]
%             disp('sig值前5个:'); disp(sig(1:min(5,end)));
%             disp('axes类型:'); disp(class(app.UIAxes2D_initial));
%             disp('axes有效性:'); disp(isvalid(app.UIAxes2D_initial));
% % 
% %             cla(app.UIAxes2D_initial);
% %             pspectrum( sig, fs_up, 'spectrogram');
% % %             colorbar(app.UIAxes2D_initial, 'off');
% %             drawnow;

            %---------------------------------------------------------
            fc= [1 1.25 1.6 2 2.5 3.15 4 5 6.3 8 10 12.5 16 20 25 31.5 40 50 63 80 100 125 160 200 250 315 400 500 630 800 1000 1250 1600 2000 2500 3150 4000 5000 6300 8000 10000 12500 16000 20000 25000 31500 40000 50000 63000 80000 100000 125000 160000 200000];
            oc6=2^(1/6);%中心频率与下限频率的比值 %oc6=1.1225
            nc=length(fc);   %nc=54
            n=length(x);%输入数据的长度 %n=2048
            nfft=2^nextpow2(n);%大于并接近n的2的幂次方长度  %2048
            a=fft(x,nfft);%FFT变换  a=(2048x1) % a复数
            yc = zeros(1,nc);
            for j=1:nc %nc=44
                fl=fc(j)/oc6;
                fu=fc(j)*oc6;  %fc=20khz对应fl=224480hz
                nl=round(fl*nfft/fs_up+1); %下限频率对应的序号
                nu=round(fu*nfft/fs_up+1);%上限频率对应的序号
                if fu>fs_up/2 %如果上限频率大于半谱频率则循环中断
                    m=j-1; 
%                     disp(m);
                    break
                end
%                 disp(j);
                b=zeros(1,nfft);
                b(nl:nu)=a(nl:nu);
                b(nfft-nu+1:nfft-nl+1)=a(nfft-nu+1:nfft-nl+1);%以每个中心频率段为通带进行带通频域滤波
                c=ifft(b,nfft);%c是复数
                yc(j)=sqrt(var(real(c(1:n)))); %信号c零均值的  %yc=RMS %每个中心频率段的RMS 	%计算对应每个中心频率段的有效值%注意，matlab函数var和std认为操作数是度量总体的一个样本，所以使用的公式是实际值与期望值之差的平方和再除以（N-1）
            end


            %----------------------时域波形---------------------------------------------
            t=0:1/fs_up:(n-1)/fs_up;
%             plot(app.TIMECH,t,x,'Color','0,0,0');
            plot(app.TIMECH,t,x,'color','0.06,0.25,0.74','LineWidth', 1.5);
            %------------三分之倍频程谱，x对应fc的数目------------------------------

            % bar(app.ONETHIRD_1_2_3_4,yc(1:m));
            
            %----------x_values = 1:m;---------bar(x_values, yc(1:m)); ---二---等宽----------------
      
            x_values = 1:m;
            bar(app.ONETHIRD_1_2_3_4,x_values, yc(1:m), 'BarWidth', 0.8); % 画柱状图
            app.ONETHIRD_1_2_3_4.XTick = x_values;
            app.ONETHIRD_1_2_3_4.XTickLabel = fc(1:m);
            
  
            %------------------------绘制三分之一倍频程连续谱-------------------------------------

            x_values = 1:m;
%             plot(app.one_third_lianxu,x_values, yc(1:m),'r-s','LineWidth',1.5); % 画连续图
            plot(app.one_third_lianxu,x_values, yc(1:m),'color','0.06,0.25,0.74','LineWidth', 1.5);
            app.one_third_lianxu.XTick = x_values;
            app.one_third_lianxu.XTickLabel = fc(1:m);


            %---------------------添功能--------------------------------

            app.Average.Value=mean(x); %在文本框显示 %每一个时间段给一个值
            app.RMS.Value=rms(x);
            app.Peak.Value=max(x);
            app.VPP.Value=peak2peak(x);
drawnow;


%---------------------- 平滑处理---------------------------------------------------------------
            % 使用从Excel读取的信号x
            signal_to_process = x;  % 替换app.add_noise为实际信号x
            
            % 生成时间轴（使用计算得到的fs_up）
            fs = fs_up; % 使用实际的采样频率
            t = (0:length(signal_to_process)-1)/fs; % 正确生成时间轴
            
            % 平滑处理
            window_size = 20;
            try
                y_smooth = smoothdata(signal_to_process, 'movmean', window_size);
            catch ME
                errordlg(['平滑处理失败: ' ME.message]);
                return;
            end

            % 统一数据长度
            min_len = min([length(t), length(signal_to_process), length(y_smooth)]);
            t = t(1:min_len);
            signal_to_process = signal_to_process(1:min_len);
            y_smooth = y_smooth(1:min_len);

            % 绘制平滑效果
            cla(app.pinghua);
            hold(app.pinghua, 'on');
            plot(app.pinghua, t, signal_to_process, 'b', 'LineWidth', 1.5);
            plot(app.pinghua, t, y_smooth, 'r', 'LineWidth', 1.5);
            hold(app.pinghua, 'off');

            title(app.pinghua, '平滑滤波效果');
            xlabel(app.pinghua, '时间 (s)');
            ylabel(app.pinghua, '幅度');
            legend(app.pinghua, {'原始信号', '平滑后'}, 'Location', 'northeast');
            grid(app.pinghua, 'on');
            drawnow;

% ----------------------- 去趋势处理 -----------------    
            % 使用内置detrend函数去除线性趋势
            y_detrend = detrend(y_smooth);       
            trend = y_smooth - y_detrend;        

            % 统一数据长度
            min_len = min([length(t), length(y_smooth), length(y_detrend)]);
            t = t(1:min_len);
            y_smooth = y_smooth(1:min_len);
            y_detrend = y_detrend(1:min_len);
            trend = trend(1:min_len);

            % 绘制去趋势结果
            cla(app.min_two_axes);
            hold(app.min_two_axes, 'on');
            plot(app.min_two_axes, t, y_smooth, 'b', 'LineWidth', 1.5);
            plot(app.min_two_axes, t, y_detrend, 'r', 'LineWidth', 1.5);
            plot(app.min_two_axes, t, trend, 'g--', 'LineWidth', 1);
            hold(app.min_two_axes, 'off');

            title(app.min_two_axes, '去趋势处理');
            xlabel(app.min_two_axes, '时间 (s)');
            ylabel(app.min_two_axes, '幅度');
            legend(app.min_two_axes, {'平滑信号', '去趋势信号', '趋势项'}, 'Location', 'northeast');
            grid(app.min_two_axes, 'on');
            drawnow;

%----------------------- FIR滤波 --------------------
            % 使用实际采样频率设计滤波器
            fir_fs = fs_up; % 重要！必须使用实际采样率
            f_low = 1;     % 通带下限频率(Hz)
            f_high = min(200000, fir_fs/2*0.9); % 确保不超过奈奎斯特频率
            
            % 调用滤波函数
            filter_signal = filter_FIR(app, fir_fs, f_low, f_high, y_detrend);
            
            % 统一数据长度
            min_len = min([length(t), length(y_detrend), length(filter_signal)]);
            t = t(1:min_len);
            y_detrend = y_detrend(1:min_len);
            filter_signal = filter_signal(1:min_len);

            % 绘制滤波效果
            cla(app.UIAxesFIR_5);
            hold(app.UIAxesFIR_5, 'on');
            plot(app.UIAxesFIR_5, t, y_detrend, 'b', 'LineWidth', 1.5);
            plot(app.UIAxesFIR_5, t, filter_signal, 'r', 'LineWidth', 1.5);
            hold(app.UIAxesFIR_5, 'off');
            
            title(app.UIAxesFIR_5, '滤波前后对比');
            xlabel(app.UIAxesFIR_5, '时间 (s)');
            ylabel(app.UIAxesFIR_5, '幅度');
            legend(app.UIAxesFIR_5, {'滤波前信号', '滤波后信号'}, 'Location', 'northeast');
            grid(app.UIAxesFIR_5, 'on');
            drawnow;
            
            set(app.Lamp_4,'color',[0.65,0.65,0.65]);


        end

        % Button pushed function: save_data
        function save_dataButtonPushed(app, event)
            %             set(app.Lamp_2,'color',[0,1,0]);%绿色
            %             set(app.Lamp,'color',[0.5 0.5 0.5]);%灰色
            %             set(app.Lamp_3,'color',[0.5 0.5 0.5]);%灰色
            %             folder = pwd; % 获取当前工作文件夹路径
            %
            %             % 将所有 UIAxes 放入一个数组中，便于循环
            %             axesList = [app.TIMECH,app.FANALYSIS ,app.FANALYSIS_2, app.ONETHIRD_1_2_3_4, ...
            %                 app.min_two_axes,app.pinghua,app.UIAxesFIR_5 ,app.one_third_lianxu];
            %
            %             for i = 1:length(axesList)
            %                 % 临时创建一个不可见图形窗口
            %                 fig = figure('Visible', 'off');
            %
            %                 % 复制 App 中的 UIAxes 到新图形
            %                 ax = copyobj(axesList(i), fig);
            %                 set(ax, 'Units', 'Normalized', 'Position', [0.13 0.11 0.775 0.815]);  % 调整位置以适配图像
            %
            %                 % 设置保存路径和文件名
            %                 app.filename = fullfile(folder, sprintf('waveform_Axes%d.png', i));
            %
            %                 % 保存为 PNG 图像（也可改为 .jpg、.eps 等）
            %                 saveas(fig, app.filename);
            %
            %                 % 关闭图形窗口
            %                 close(fig);
            %             end
            %           set(app.Lamp_2, 'Color', [0.5 0.5 0.5]);%灰色

%             try
                % 检查 Lamp_2 是否存在且有效
                if isvalid(app.Lamp_2)
                    set(app.Lamp_2, 'color', [0,1,0]); % 绿色
                else
                    warning('Lamp_2 不存在或已删除，无法设置颜色');
                    return;
                end

                % 设置其他 Lamp 颜色
                set(app.Lamp, 'color', [0.5 0.5 0.5]); % 灰色
                set(app.Lamp_3, 'color', [0.5 0.5 0.5]); % 灰色

                % 保存坐标轴内容
                folder = pwd;
                axesList = [app.TIMECH, app.FANALYSIS, app.FANALYSIS_2, app.ONETHIRD_1_2_3_4, ...
                    app.min_two_axes, app.pinghua, app.UIAxesFIR_5, app.one_third_lianxu];

                for i = 1:length(axesList)
                    ax = axesList(i);
                    if isvalid(ax)
                        try
                            app.filename = fullfile(folder, sprintf('waveform_Axes%d.png', i));
                            exportgraphics(ax, app.filename, 'Resolution', 300);
                        catch ME
                            warning('坐标轴 %d 保存失败: %s', i, ME.message);
                        end
                    else
                        warning('坐标轴 %d 无效，跳过保存', i);
                    end
                end

                % 最终设置 Lamp_2 颜色前再次检查有效性
                if isvalid(app.Lamp_2)
                    set(app.Lamp_2, 'Color', [0.5 0.5 0.5]); % 灰色
                else
                    warning('Lamp_2 已被删除，无法恢复颜色');
                end

%             catch ME
%                 % 捕获全局异常并提示
%                 errordlg(sprintf('保存过程中发生错误: %s', ME.message), '错误');
%             end


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Callback function
        function DropDownOpening(app, event)
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)

            app.value_1 = app.ListBox.Value;

            % ================== Excel数据处理 ==================
            try
                Signal = xlsread(app.FILEPATH.Value);
                t = Signal(:,1)-1;
                dt = t(2)-t(1);
                fs_up = 1/dt;
                x = Signal(:,2);

                % 关键修正：基于FFT长度计算
                n = length(x);
                nfft = 2^nextpow2(n);
                a = fft(x, nfft);
                ff = linspace(0, fs_up/2, nfft/2 + 1); % 频率轴长度应为nfft/2+1

                % 计算幅值/功率谱（统一使用nfft长度）
                Amplitude = 2*abs(a(1:nfft/2+1))/nfft;
                Amplitude(1) = Amplitude(1)/2; % 直流分量修正
                Power = 20*log10(Amplitude.^2);
            catch
                errordlg('Excel数据读取失败');
                return;
            end

            % ================== 自定义信号处理 ==================
            try
                fs_custom = 450000; % 自定义信号采样率
                app.y_total_ac = app.y_total_ac; % 假设已定义

                n_custom = length(app.y_total_ac);
                nfft_custom = 2^nextpow2(n_custom);
                b = fft(app.y_total_ac, nfft_custom);
                ff_custom = linspace(0, fs_custom/2, nfft_custom/2 + 1);

                Amplitude_custom = 2*abs(b(1:nfft_custom/2+1))/nfft_custom;
                Amplitude_custom(1) = Amplitude_custom(1)/2;
                Power_custom = 20*log10(Amplitude_custom.^2);
            catch
                errordlg('自定义信号处理失败');
                return;
            end

            % ================== 绘图 ==================
            cla(app.FANALYSIS);
            cla(app.FANALYSIS_2);

            switch app.value_1    %1.00,0.00,1.00
                case '功率谱'
                    % Excel数据绘图
                    plot(app.FANALYSIS, log10(ff), Power, 'color','0.06,0.25,0.74', 'LineWidth', 1);

                    % 自定义信号绘图
                    plot(app.FANALYSIS_2, log10(ff_custom), Power_custom,'color','0.73,0.05,0.11' , 'LineWidth', 1);

                case '幅频'
                    plot(app.FANALYSIS, log10(ff), Amplitude,'color','0.06,0.25,0.74',  'LineWidth', 1.5);
                    plot(app.FANALYSIS_2, log10(ff_custom), Amplitude_custom,'color','0.73,0.05,0.11'  ,'LineWidth', 1.5);

                case '相频'
                    ph = angle(a(1:nfft/2+1))*180/pi;
                    ph_custom = angle(b(1:nfft_custom/2+1))*180/pi;
                    plot(app.FANALYSIS, log10(ff), ph, 'color','0.06,0.25,0.74', 'LineWidth', 1.5);
                    plot(app.FANALYSIS_2, log10(ff_custom), ph_custom, 'color','0.73,0.05,0.11' ,'LineWidth', 1.5);

                case '实频'
                    r = real(a(1:nfft/2+1));
                    r_custom = real(b(1:nfft_custom/2+1));
                    plot(app.FANALYSIS, log10(ff), r,'color','0.06,0.25,0.74', 'LineWidth', 1.5);
                    plot(app.FANALYSIS_2, log10(ff_custom), r_custom,'color','0.73,0.05,0.11' ,'LineWidth', 1.5);

                case '虚 频'
                    ii = imag(a(1:nfft/2+1));
                    ii_custom = imag(b(1:nfft_custom/2+1));
                    plot(app.FANALYSIS, log10(ff), ii, 'color','0.06,0.25,0.74', 'LineWidth', 1.5);
                    plot(app.FANALYSIS_2, log10(ff_custom), ii_custom,'color','0.73,0.05,0.11' , 'LineWidth', 1.5);
            end

            % 统一设置坐标标签
            xlabel(app.FANALYSIS, 'log_{10}(f)');
            xlabel(app.FANALYSIS_2, 'log_{10}(f)');
            grid(app.FANALYSIS, 'on');
            grid(app.FANALYSIS_2, 'on');
            drawnow;


        end

        % Button pushed function: clear_process
        function clear_processButtonPushed(app, event)
            set(app.Lamp_3,'color',[1.00,0.00,0.00]);%红色
            set(app.Lamp,'color',[0.50,0.50,0.50]);%灰色
            cla(app.one_third_lianxu) %清除其中的值
%             cla(app.ONETHIRD_fc)
            cla(app.ONETHIRD_1_2_3_4)
            cla(app.FANALYSIS)
            cla(app.TIMECH)
            cla(app.min_two_axes)
            cla(app.pinghua)
            cla(app.UIAxesFIR_5)
            cla(app.FANALYSIS_2)
        end

        % Callback function
        function ListBox_2ValueChanged(app, event)
            value = app.ListBox_2.Value;
                switch value %复选框
                case(' EXCEL数据')
             
%                     plot(app.FANALYSIS,log10(ff),Power(1:ceil(n/2)),'Color','0, 0, 0');%db(功率谱)
%                 case('自定义波形')
%                     plot(app.FANALYSIS,log10(ff),Amplitude(1:ceil(n/2)),'Color','0, 0, 0');%x轴hz  y轴Y
            end
            drawnow; 
        end

        % Value changed function: FILEPATH
        function FILEPATHValueChanged(app, event)
%             value = app.FILEPATH.Value;

        end

        % Button pushed function: addnosie
        function addnosieButtonPushed(app, event)
             t = 0:0.001:1;  % 时间向量

            % 从控件获取参数
            Adc = app.zhiliu.Value;  % 直流分量
            A1 = app.fuzhi1.Value;
            f1 = app.pinlv_1.Value;
            phi1_deg = app.phi_1.Value;  % 相位（度）
            phi1 = deg2rad(phi1_deg);     % 转换为弧度
            type1 = app.DropDown.Value;

            A2 = app.fuzhi2.Value;
            f2 = app.pinlv_2.Value;
            phi2 = deg2rad(app.phi_2.Value);
            type2 = app.DropDown_2.Value;

            A3 = app.fuzhi3.Value;
            f3 = app.pinlv_3.Value;
            phi3 = deg2rad(app.phi_3.Value);
            type3 = app.DropDown_3.Value;

            % 调用类方法生成波形（关键修复点：添加 app. 前缀）
            y1 = A1 * app.waveform(type1, f1, t, phi1);
            y2 = A2 * app.waveform(type2, f2, t, phi2);
            y3 = A3 * app.waveform(type3, f3, t, phi3);

            % 合成总信号
            app.y_total = Adc + y1 + y2 + y3;

            % 添加高斯白噪声
            app.add_noise = app.y_total + 2*randn(size(t)); % 使用 app.y_total
            
            % 绘制含噪声的信号
            plot(app.TIMECH, t, app.add_noise,'color','[0.76,0.03,0.54]', 'LineWidth', 1.5);
            title(app.TIMECH, '加噪声后的信号');
            xlabel(app.TIMECH, '时间 (s)');
            ylabel(app.TIMECH, '幅度');
            grid(app.TIMECH, 'on');

        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)


        end

        % Button pushed function: monixinhao_shiyu
        function monixinhao_shiyuButtonPushed(app, event)
            % T = 2;  % 总时长（秒）%T的间隔=1.220703125000000e-04
            % N = fs * T;  % N=16384 总采样点数
            % Ts = 1/fs;% 采样间隔
            % t =0:Ts:T-Ts;
            
            
            app.event_1=event;
            global y_total
            % t = 0:0.001:1;  % 时间向量
             t=0:1/8192:2-1/8192;

            % 从控件获取参数
            Adc = app.zhiliu.Value;  % 直流分量
            A1 = app.fuzhi1.Value;
            f1 = app.pinlv_1.Value;
            phi1_deg = app.phi_1.Value;  % 相位（度）
            phi1 = deg2rad(phi1_deg);     % 转换为弧度
            type1 = app.DropDown.Value;

            A2 = app.fuzhi2.Value;
            f2 = app.pinlv_2.Value;
            phi2 = deg2rad(app.phi_2.Value);
            type2 = app.DropDown_2.Value;

            A3 = app.fuzhi3.Value;
            f3 = app.pinlv_3.Value;
            phi3 = deg2rad(app.phi_3.Value);
            type3 = app.DropDown_3.Value;

            % 调用类方法生成波形（关键修复点：添加 app. 前缀）
            y1 = A1 * app.waveform(type1, f1, t, phi1);
            y2 = A2 * app.waveform(type2, f2, t, phi2);
            y3 = A3 * app.waveform(type3, f3, t, phi3);
            
            app.y_total_ac=y1 + y2 + y3;

            % 合成总信号
            y_total = Adc + y1 + y2 + y3;

            % 绘制时域波形
            plot(app.TIMECH, t, y_total,'color','[0.76,0.03,0.54]', 'LineWidth', 1.5); % 洋红色
            title(app.TIMECH, '合成信号时域波形');
            xlabel(app.TIMECH, '时间 (s)');
            ylabel(app.TIMECH, '幅度');
            grid(app.TIMECH, 'on');

        end

        % Callback function
        function begin_5ButtonPushed(app, event)
            %  begin_5 (预处理)

            %             if app.isExcelDataLoaded

            Signal = xlsread(app.FILEPATH.Value);   % 读Excel文件
            t = Signal(:,1)-1;            % di一列时间序列，减1处理
            dt = t(2)-t(1);               % 时间间隔（假设是均匀采样）这段代码就是针对等时间间隔的采样。
            fs_up = 1/dt;  %fs_up=8192 奈奎斯特采样频率
            x=Signal(:,2);%速度
            %%
            %---------------------------------------------------------
            fc= [1 1.25 1.6 2 2.5 3.15 4 5 6.3 8 10 12.5 16 20 25 31.5 40 50 63 80 100 125 160 200 250 315 400 500 630 800 1000 1250 1600 2000 2500 3150 4000 5000 6300 8000 10000 12500 16000 20000 25000 31500 40000 50000 63000 80000 100000 125000 160000 200000];
            oc6=2^(1/6);%中心频率与下限频率的比值 %oc6=1.1225
            nc=length(fc);   %nc=54
            n=length(x);%输入数据的长度 %n=2048
            nfft=2^nextpow2(n);%大于并接近n的2的幂次方长度  %2048
            a=fft(x,nfft);%FFT变换  a=(2048x1) % a复数
            yc = zeros(1,nc);
            for j=1:nc %nc=44
                fl=fc(j)/oc6;
                fu=fc(j)*oc6;  %fc=20khz对应fl=224480hz
                nl=round(fl*nfft/fs_up+1); %下限频率对应的序号
                nu=round(fu*nfft/fs_up+1);%上限频率对应的序号
                if fu>fs_up/2 %如果上限频率大于半谱频率则循环中断
                    m=j-1; disp(m);
                    break
                end
                disp(j);
                b=zeros(1,nfft);
                b(nl:nu)=a(nl:nu);
                b(nfft-nu+1:nfft-nl+1)=a(nfft-nu+1:nfft-nl+1);%以每个中心频率段为通带进行带通频域滤波
                c=ifft(b,nfft);%c是复数
                yc(j)=sqrt(var(real(c(1:n)))); %信号c零均值的  %yc=RMS %每个中心频率段的RMS 	%计算对应每个中心频率段的有效值%注意，matlab函数var和std认为操作数是度量总体的一个样本，所以使用的公式是实际值与期望值之差的平方和再除以（N-1）
            end


            %----------------------滤波前时域波形---------------------------------------------
            t=0:1/fs_up:(n-1)/fs_up;
            plot(app.TIMECH,t,x,'Color','0,0,0');


        end

        % Button pushed function: begin_6
        function begin_6ButtonPushed(app, event)
            %             set(app.Lamp_4,'color',[0,1,0]);%设置信号灯
            % %             set(app.Lamp_4,'color',[0.65,0.65,0.65]);%设置灰色
        fc= [1 1.25 1.6 2 2.5 3.15 4 5 6.3 8 10 12.5 16 20 25 31.5 40 50 63 80 100 125 160 200 250 315 400 500 630 800 1000 1250 1600 2000 2500 3150 4000 5000 6300 8000 10000 12500 16000 20000 25000 31500 40000 50000 63000 80000 100000 125000 160000 200000];
      
%----------------------  平滑处理---------------------------------------------------------------
            set(app.Lamp_4, 'color', [0,1,0]);

            % 检查噪声数据是否存在
            if isempty(app.add_noise)
                errordlg('未找到噪声数据，请先添加噪声');
                return;
            end

            % 生成时间轴
            fs = 8192;
            t = 0:1/fs:(length(app.add_noise)-1)/fs; % 动态生成时间轴

            % 平滑处理
            window_size = 20;
            try
                y_smooth = smoothdata(app.add_noise, 'movmean', window_size);
            catch ME
                errordlg(['平滑处理失败: ' ME.message]);
                return;
            end

            % 统一数据长度
            min_len = min([length(t), length(app.add_noise), length(y_smooth)]);
            t = t(1:min_len);
            app.add_noise = app.add_noise(1:min_len);
            y_smooth = y_smooth(1:min_len);

            % 绘图
            cla(app.pinghua);
            hold(app.pinghua, 'on');
            plot(app.pinghua, t, app.add_noise, 'b', 'LineWidth', 1.5);
            plot(app.pinghua, t, y_smooth, 'r', 'LineWidth', 1.5);
            hold(app.pinghua, 'off');

            % 美化图形
            title(app.pinghua, '平滑滤波效果');
            xlabel(app.pinghua, '时间 (s)');
            ylabel(app.pinghua, '幅度');
            legend(app.pinghua, {'原始信号', '平滑后'}, 'Location', 'northeast');
            grid(app.pinghua, 'on');
            drawnow;


% ----------------------- 去趋势处理 (选择方案A或B) -----------------
    
            %  使用内置 detrend 函数
            y_detrend = detrend(y_smooth);       % 去线性趋势
            trend = y_smooth - y_detrend;        % 趋势项

            % B: 使用自定义 polydetrend 函数
            % [y_detrend, trend] = polydetrend(y_smooth, fs, 1);%麻烦

            % 统一数据长度
            min_len = min([length(t), length(y_smooth), length(y_detrend)]);
            t = t(1:min_len);
            y_smooth = y_smooth(1:min_len);
            y_detrend = y_detrend(1:min_len);
            trend = trend(1:min_len);

            % 绘图
            cla(app.min_two_axes);
            hold(app.min_two_axes, 'on');
            plot(app.min_two_axes, t, y_smooth, 'b', 'LineWidth', 1.5);
            plot(app.min_two_axes, t, y_detrend, 'r', 'LineWidth', 1.5);
            plot(app.min_two_axes, t, trend, 'g--', 'LineWidth', 1);
            hold(app.min_two_axes, 'off');

            % 图形美化
            title(app.min_two_axes, '去趋势处理');
            xlabel(app.min_two_axes, '时间 (s)');
            ylabel(app.min_two_axes, '幅度');
            legend(app.min_two_axes, {'平滑信号', '去趋势信号', '趋势项'}, 'Location', 'northeast');
            grid(app.min_two_axes, 'on');
            drawnow;


%-----------------------fir滤波--------------------
            fir_fs=450000;
            filter_signal = filter_FIR(app,fir_fs, 1,200000 , y_smooth);
            cla(app.UIAxesFIR_5);
            hold(app.UIAxesFIR_5, 'on');
            plot(app.UIAxesFIR_5, t, y_smooth, 'b', 'LineWidth', 1.5);
             plot(app.UIAxesFIR_5, t,filter_signal, 'r', 'LineWidth', 1.5);
            hold(app.UIAxesFIR_5, 'off');
            
            % 图形美化
            title(app.UIAxesFIR_5, '滤波前');% 就是平滑后的信号y_smooth
            xlabel(app.UIAxesFIR_5, '时间 (s)');
            ylabel(app.UIAxesFIR_5, '幅度');
            legend(app.UIAxesFIR_5, {'滤波前信号', '滤波后信号'}, 'Location', 'northeast');
            %频率太低，没有用滤掉，两个信号重叠了
            grid(app.UIAxesFIR_5, 'on');
            drawnow;
            
            set(app.Lamp_4,'color',[0.65,0.65,0.65]);
  
%-------------------------三分之一倍频程分析-------------使用生成时域信号(无噪信号)------            
            oc6=2^(1/6);%中心频率与下限频率的比值 %oc6=1.1225
            nc=length(fc);%取中心频率总的长度  %nc=54
            n=length(app.y_total_ac);%输入数据的长度 %n=2048
            nfft=2^nextpow2(n);%大于并接近n的2的幂次方长度  %2048
            a=fft(app.y_total_ac,nfft);%FFT变换  a=(2048x1) % a复数
            %%
            yc = zeros(1,nc);
            for j=1:nc %nc=44
                fl=fc(j)/oc6;
                fu=fc(j)*oc6;  %fc=20khz对应fl=224480hz
                nl=round(fl*nfft/fs+1); %下限频率对应的序号
                nu=round(fu*nfft/fs+1);%上限频率对应的序号
                if fu>fs/2 %如果上限频率大于半谱频率则循环中断
                    m=j-1;
                    break  %判断有效的频率，当一旦出现频率值超过fs/2时，立即停止后边数据的检查，因为出现了f太大的信号，不符合奈奎斯特定律
                end
                b=zeros(1,nfft);%1x2048
                b(nl:nu)=a(nl:nu);
                b(nfft-nu+1:nfft-nl+1)=a(nfft-nu+1:nfft-nl+1);%以每个中心频率段为通带进行带通频域滤波
                c=ifft(b,nfft);%c是复数
                yc(j)=sqrt(var(real(c(1:n)))); %该公式认为信号c是零均值的  %yc=RMS %每个中心频率段的RMS 	%计算对应每个中心频率段的有效值%注意，matlab函数var和std认为操作数是度量总体的一个样本，所以使用的公式是实际值与期望值之差的平方和再除以（N-1）
            end           
%             %----------------------时域波形---------------------------------------------
%             t=0:1/fs:(n-1)/fs;
%             plot(app.TIMECH,t, app.y_total_ac,'Color','0.00,0.00,1.00','LineWidth', 1);

            
            %----------x_values = 1:m;---------bar(x_values, yc(1:m)); ---二---等宽----------------
      
            x_values = 1:m;
            bar(app.ONETHIRD_1_2_3_4,x_values, yc(1:m), 'BarWidth', 0.8); % 画柱状图
            app.ONETHIRD_1_2_3_4.XTick = x_values;
            app.ONETHIRD_1_2_3_4.XTickLabel = fc(1:m);
            
  
            %------------------------绘制三分之一倍频程连续谱-------------------------------------

            x_values = 1:m;
            plot(app.one_third_lianxu,x_values, yc(1:m),'color','0.06,0.25,0.74','LineWidth',1.5);
            app.one_third_lianxu.XTick = x_values;
            app.one_third_lianxu.XTickLabel = fc(1:m);


            %---------------------添功能--------------------------------

            app.Average.Value=mean(app.y_total_ac); %在文本框显示 %每一个时间段给一个值
            app.RMS.Value=rms(app.y_total_ac);
            app.Peak.Value=max(app.y_total_ac);
            app.VPP.Value=peak2peak(app.y_total_ac);

drawnow;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FANALYSIS = uiaxes(app.UIFigure);
            title(app.FANALYSIS, '\color[rgb]{0,0,0}\fontname{Times New Roman,宋体}频域特征-1')
            xlabel(app.FANALYSIS, {'\fontname{Times New Roman,宋体}\color[rgb]{0,0,0}log(ff)'; ''})
            ylabel(app.FANALYSIS, 'Y')
            zlabel(app.FANALYSIS, 'Z')
            app.TIMECH = uiaxes(app.UIFigure);
            title(app.TIMECH, '\color[rgb]{0,0,0}\fontname{Times New Roman,宋体} 信号时域波形')
            xlabel(app.TIMECH, '\fontname{Times New Roman,宋体}\color[rgb]{0,0,0}时间(s)')
            ylabel(app.TIMECH, '\color[rgb]{0,0,0}\fontname{Times New Roman,宋体} 加速度 mm/s^2')
            zlabel(app.TIMECH, 'Z')
            app.ONETHIRD_1_2_3_4 = uiaxes(app.UIFigure);
            title(app.ONETHIRD_1_2_3_4, '\color[rgb]{0,0,0}\fontname{Times New Roman,宋体}三分之一倍频(x=1:m)')
            xlabel(app.ONETHIRD_1_2_3_4, {'\fontname{Times New Roman,宋体}\color[rgb]{0,0,0}x_values'; ''})
            ylabel(app.ONETHIRD_1_2_3_4, '\color[rgb]{0,0,0}\fontname{Times New Roman,宋体} 分贝(db)')
            zlabel(app.ONETHIRD_1_2_3_4, 'Z')
            app.one_third_lianxu = uiaxes(app.UIFigure);
            title(app.one_third_lianxu, '\color[rgb]{0,0,0}\fontname{Times New Roman,宋体}三分之一倍频程（连续）')
            xlabel(app.one_third_lianxu, '\fontname{Times New Roman,宋体}\color[rgb]{0,0,0}频率(Hz)')
            ylabel(app.one_third_lianxu, '\color[rgb]{0,0,0}\fontname{Times New Roman,宋体} RMS值(dB)')
            zlabel(app.one_third_lianxu, 'Z')
            app.one_third_lianxu.XScale = 'log';
            app.min_two_axes = uiaxes(app.UIFigure);
            title(app.min_two_axes, '最小二乘法')
            xlabel(app.min_two_axes, 'X')
            ylabel(app.min_two_axes, 'Y')
            zlabel(app.min_two_axes, 'Z')
            app.pinghua = uiaxes(app.UIFigure);
            title(app.pinghua, '平滑滤波')
            xlabel(app.pinghua, 'X')
            ylabel(app.pinghua, 'Y')
            zlabel(app.pinghua, 'Z')
            app.UIAxesFIR_5 = uiaxes(app.UIFigure);
            title(app.UIAxesFIR_5, 'FIR滤波')
            xlabel(app.UIAxesFIR_5, 'X')
            ylabel(app.UIAxesFIR_5, 'Y')
            zlabel(app.UIAxesFIR_5, 'Z')
            app.FANALYSIS_2 = uiaxes(app.UIFigure);
            title(app.FANALYSIS_2, '\color[rgb]{0,0,0}\fontname{Times New Roman,宋体}频域特征-2')
            xlabel(app.FANALYSIS_2, {'\fontname{Times New Roman,宋体}\color[rgb]{0,0,0}log(ff)'; ''})
            ylabel(app.FANALYSIS_2, 'Y')
            zlabel(app.FANALYSIS_2, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '文件路径';
            app.FILEPATH = uieditfield(app.UIFigure, 'text');
            app.FILEPATH.ValueChangedFcn = createCallbackFcn(app, @FILEPATHValueChanged, true);
            app.CHOOSEPATH = uibutton(app.UIFigure, 'push');
            app.CHOOSEPATH.ButtonPushedFcn = createCallbackFcn(app, @CHOOSEPATHButtonPushed, true);
            app.CHOOSEPATH.Text = '选择文件';
            app.begin = uibutton(app.UIFigure, 'push');
            app.begin.ButtonPushedFcn = createCallbackFcn(app, @beginButtonPushed, true);
            app.begin.Text = '开始处理';
            app.Lamp = uilamp(app.UIFigure);
            app.save_data = uibutton(app.UIFigure, 'push');
            app.save_data.ButtonPushedFcn = createCallbackFcn(app, @save_dataButtonPushed, true);
            app.save_data.Text = '保存数据';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '峰值';
            app.Peak = uieditfield(app.UIFigure, 'numeric');
            app.Peak.Editable = 'off';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '峰峰值';
            app.VPP = uieditfield(app.UIFigure, 'numeric');
            app.VPP.Editable = 'off';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '平均值';
            app.Average = uieditfield(app.UIFigure, 'numeric');
            app.Average.Editable = 'off';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '有效值';
            app.RMS = uieditfield(app.UIFigure, 'numeric');
            app.RMS.Editable = 'off';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '(保存波形数据)';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '频域特征';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'幅频', '相频', '实频', '虚 频', '功率谱'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Tooltip = {'幅频(x轴hz)'; '相频(x轴hz,y轴弧度)'; '实频(x轴hz)'; '虚频(x轴hz)'; '功率谱(x轴hz,y轴分贝)'};
            app.ListBox.Value = '相频';
            app.clear_process = uibutton(app.UIFigure, 'push');
            app.clear_process.ButtonPushedFcn = createCallbackFcn(app, @clear_processButtonPushed, true);
            app.clear_process.Text = '清除数据';
            app.AdcLabel = uilabel(app.UIFigure);
            app.AdcLabel.Text = 'Adc';
            app.Lamp_3 = uilamp(app.UIFigure);
            app.zhiliu = uieditfield(app.UIFigure, 'numeric');
            app.A1Label = uilabel(app.UIFigure);
            app.A1Label.Text = 'A1';
            app.fuzhi1 = uieditfield(app.UIFigure, 'numeric');
            app.fuzhi1.Value = 2;
            app.A2Label = uilabel(app.UIFigure);
            app.A2Label.Text = 'A2';
            app.fuzhi2 = uieditfield(app.UIFigure, 'numeric');
            app.fuzhi2.Value = 6;
            app.A3Label = uilabel(app.UIFigure);
            app.A3Label.Text = 'A3';
            app.fuzhi3 = uieditfield(app.UIFigure, 'numeric');
            app.fuzhi3.Value = 9;
            app.F1Label = uilabel(app.UIFigure);
            app.F1Label.Text = 'F1';
            app.pinlv_1 = uieditfield(app.UIFigure, 'numeric');
            app.pinlv_1.Value = 10;
            app.F2Label = uilabel(app.UIFigure);
            app.F2Label.Text = 'F2';
            app.pinlv_2 = uieditfield(app.UIFigure, 'numeric');
            app.pinlv_2.Value = 500;
            app.F3Label = uilabel(app.UIFigure);
            app.F3Label.Text = 'F3';
            app.pinlv_3 = uieditfield(app.UIFigure, 'numeric');
            app.pinlv_3.Value = 800;
            app.addnosie = uibutton(app.UIFigure, 'push');
            app.addnosie.ButtonPushedFcn = createCallbackFcn(app, @addnosieButtonPushed, true);
            app.addnosie.Text = '添加高斯白噪声';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '波形1';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'sin', 'cos'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'sin';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '波形2';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'sin', 'cos'};
            app.DropDown_2.Value = 'cos';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '波形3';
            app.DropDown_3 = uidropdown(app.UIFigure);
            app.DropDown_3.Items = {'sin', 'cos'};
            app.DropDown_3.Value = 'sin';
            app.P1Label = uilabel(app.UIFigure);
            app.P1Label.Text = 'P1';
            app.phi_1 = uieditfield(app.UIFigure, 'numeric');
            app.P2Label = uilabel(app.UIFigure);
            app.P2Label.Text = 'P2';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '自定义波形';
            app.phi_2 = uieditfield(app.UIFigure, 'numeric');
            app.P3Label = uilabel(app.UIFigure);
            app.P3Label.Text = 'P3';
            app.phi_3 = uieditfield(app.UIFigure, 'numeric');
            app.monixinhao_shiyu = uibutton(app.UIFigure, 'push');
            app.monixinhao_shiyu.ButtonPushedFcn = createCallbackFcn(app, @monixinhao_shiyuButtonPushed, true);
            app.monixinhao_shiyu.Text = '生成时域波形';
            app.begin_6 = uibutton(app.UIFigure, 'push');
            app.begin_6.ButtonPushedFcn = createCallbackFcn(app, @begin_6ButtonPushed, true);
            app.begin_6.Text = '开始处理';
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '预处理区域';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'xiaohui.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = xjm3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end